classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image6                          matlab.ui.control.Image
        createdbyHungNguyenLabel        matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        SPAMCLASSIFICATIONLabel         matlab.ui.control.Label
        RESULTTextArea                  matlab.ui.control.TextArea
        RESULTTextAreaLabel             matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        ClassifyuremailSpamornotButton  matlab.ui.control.Button
        TrainthemodelusingavailabledatasetButton  matlab.ui.control.Button
        ChooseyouremailtxtButton        matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        file % Description
        model
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseyouremailtxtButton
        function ChooseyouremailtxtButtonPushed(app, event)
            clc;
            [file,~] = uigetfile('*.txt');
            preview = readFile(file);
            preview
            app.RESULTTextArea.Value = 'Read successful !!!';
            temp = regexp(preview, '\r?\n', 'split');
            app.RESULTTextArea.Value = temp;
            app.file = preview;
            
        end

        % Button pushed function: 
        % TrainthemodelusingavailabledatasetButton
        function TrainthemodelusingavailabledatasetButtonPushed(app, event)
            app.TrainthemodelusingavailabledatasetButton.Text = 'Processing...';
            app.TrainthemodelusingavailabledatasetButton.IconAlignment = 'bottom';
            wbar = permute(repmat(app.TrainthemodelusingavailabledatasetButton.BackgroundColor,15,1,500),[1,3,2]);
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            app.TrainthemodelusingavailabledatasetButton.Icon = wbar;
            n = 10;
            for i = 1:n
                % Update image data (royalblue)
                currentProg = min(round((size(wbar,2)-2)*(i/n)),size(wbar,2)-2);
                app.TrainthemodelusingavailabledatasetButton.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.TrainthemodelusingavailabledatasetButton.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.TrainthemodelusingavailabledatasetButton.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                % Pause to slow down animation
                pause(.3)
            end
            clc;
            load spamTrain.mat;
            C = 0.1;
            model = svmTrain(X, y, C, @linearKernel);
            
            p = svmPredict(model, X);
            fprintf('Training Accuracy: %f\n', mean(double(p == y)) * 100);

            % Load the test dataset
            % You will have Xtest, ytest in your environment
            load spamTest.mat;
            
            p = svmPredict(model, Xtest);
            fprintf('Test Accuracy: %f\n', mean(double(p == ytest)) * 100);
            app.RESULTTextArea.Value = 'Training Done !!!';
            app.model = model;
            app.TrainthemodelusingavailabledatasetButton.Icon = '';
            % Change button name
            app.TrainthemodelusingavailabledatasetButton.Text = 'TrainthemodelusingavailabledatasetButton';
        end

        % Button pushed function: ClassifyuremailSpamornotButton
        function ClassifyuremailSpamornotButtonPushed(app, event)
            clc;
            
            word_indices = processEmail(app.file);
            
            features = emailFeatures(word_indices);
            
            p = svmPredict(app.model, features);
            
            if (p == 0) 
                app.RESULTTextArea.Value = 'NOT SPAM !!!';
            else 
                app.RESULTTextArea.Value = 'SPAM !!!';     
            end
        end

        % Callback function
        function ResultEditFieldValueChanged(app, event)
            value = app.ResultEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'cau vang 8 bit.png';
            app.ChooseyouremailtxtButton = uibutton(app.UIFigure, 'push');
            app.ChooseyouremailtxtButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseyouremailtxtButtonPushed, true);
            app.ChooseyouremailtxtButton.Text = 'Choose your email (.txt)';
            app.TrainthemodelusingavailabledatasetButton = uibutton(app.UIFigure, 'push');
            app.TrainthemodelusingavailabledatasetButton.ButtonPushedFcn = createCallbackFcn(app, @TrainthemodelusingavailabledatasetButtonPushed, true);
            app.TrainthemodelusingavailabledatasetButton.Text = 'Train the model using available data-set';
            app.ClassifyuremailSpamornotButton = uibutton(app.UIFigure, 'push');
            app.ClassifyuremailSpamornotButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyuremailSpamornotButtonPushed, true);
            app.ClassifyuremailSpamornotButton.Text = 'Classify ur email ->Spam or not?';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'giphy.gif';
            app.RESULTTextAreaLabel = uilabel(app.UIFigure);
            app.RESULTTextAreaLabel.Text = 'RESULT';
            app.RESULTTextArea = uitextarea(app.UIFigure);
            app.SPAMCLASSIFICATIONLabel = uilabel(app.UIFigure);
            app.SPAMCLASSIFICATIONLabel.Text = 'SPAM CLASSIFICATION';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'pikachu.jpg';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'charizard.jpg';
            app.createdbyHungNguyenLabel = uilabel(app.UIFigure);
            app.createdbyHungNguyenLabel.Text = '- created by Hung Nguyen -';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'tenor.gif';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end